classdef texture_analysis_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        uipanel1     matlab.ui.container.Panel
        uitable1     matlab.ui.control.Table
        edit1        matlab.ui.control.EditField
        text2        matlab.ui.control.Label
        pushbutton3  matlab.ui.control.Button
        pushbutton2  matlab.ui.control.Button
        pushbutton1  matlab.ui.control.Button
        axes2        matlab.ui.control.UIAxes
        axes1        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.uitable1.ColumnFormat = {[] [] [] [] []};
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function texture_analysis_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to texture_analysis (see VARARGIN)
            
            % Choose default command line output for texture_analysis
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            movegui(hObject,'center');
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % menampilkan menu "browse image"
            [nama_file, nama_folder] = uigetfile('*.jpeg');
            
            % jika ada nama file yg dipilih maka akan mengeksekusi perintah di bawah
            % ini
            if ~isequal(nama_file,0)
                % membaca file citra rgb
                Img = imread(fullfile(nama_folder,nama_file));
                % menampilkan citra rgb pada axes
                axes(handles.axes1)
                imshow(Img)
                title('RGB Image')
                % menyimpan variabel Img pada lokasi handles agar dapat dipanggil oleh
                % pushbutton yg lain
                handles.Img = Img;
                guidata(hObject, handles)
            else
            % jika tidak ada nama file yg dipilih maka akan kembali
            return
            end
        end

        % Button pushed function: pushbutton2
        function pushbutton2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % memanggil variabel Img yg ada di lokasi handles
            Img = handles.Img;
            
            % mengkonversi citra rgb menjadi citra grayscale
            Img_gray = rgb2gray(Img);
            
            % menampilkan citra grayscale pada axes
            axes(handles.axes2)
            imshow(Img_gray)
            title('Grayscale Image')
            
            % membaca pixel distance yg ada pada edit text
            pixel_dist = str2double(get(handles.edit1,'String'));
            % membentuk matriks kookurensi
            GLCM = graycomatrix(Img_gray,'Offset',[0 pixel_dist;...
                -pixel_dist pixel_dist; -pixel_dist 0; -pixel_dist -pixel_dist]);
            % mengestrak fitur GLCM
            stats = graycoprops(GLCM,{'Contrast','Correlation','Energy','Homogeneity'});
            
            % membaca fitur GLCM
            Contrast = stats.Contrast;
            Correlation = stats.Correlation;
            Energy = stats.Energy;
            Homogeneity = stats.Homogeneity;
            
            % menampilkan fitur GLCM pada tabel
            data = get(handles.uitable1,'Data');
            data{1,1} = num2str(Contrast(1));
            data{1,2} = num2str(Contrast(2));
            data{1,3} = num2str(Contrast(3));
            data{1,4} = num2str(Contrast(4));
            data{1,5} = num2str(mean(Contrast));
            
            data{2,1} = num2str(Correlation(1));
            data{2,2} = num2str(Correlation(2));
            data{2,3} = num2str(Correlation(3));
            data{2,4} = num2str(Correlation(4));
            data{2,5} = num2str(mean(Correlation));
            
            data{3,1} = num2str(Energy(1));
            data{3,2} = num2str(Energy(2));
            data{3,3} = num2str(Energy(3));
            data{3,4} = num2str(Energy(4));
            data{3,5} = num2str(mean(Energy));
            
            data{4,1} = num2str(Homogeneity(1));
            data{4,2} = num2str(Homogeneity(2));
            data{4,3} = num2str(Homogeneity(3));
            data{4,4} = num2str(Homogeneity(4));
            data{4,5} = num2str(mean(Homogeneity));
            
            set(handles.uitable1,'Data',data)
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            axes(handles.axes1)
            cla reset
            set(gca,'Xtick',[])
            set(gca,'Ytick',[])
            
            axes(handles.axes2)
            cla reset
            set(gca,'Xtick',[])
            set(gca,'Ytick',[])
            
            set(handles.uitable1,'Data',[])
            set(handles.edit1,'String','1')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'texture_analysis';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.XTick = [];
            app.axes1.YTick = [];
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.figure1);
            app.axes2.XTick = [];
            app.axes2.YTick = [];
            app.axes2.Tag = 'axes2';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Texture Analysis';
            app.uipanel1.Tag = 'uipanel1';
            app.pushbutton1 = uibutton(app.uipanel1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Browse Image';
            app.pushbutton2 = uibutton(app.uipanel1, 'push');
            app.pushbutton2.ButtonPushedFcn = createCallbackFcn(app, @pushbutton2_Callback, true);
            app.pushbutton2.Tag = 'pushbutton2';
            app.pushbutton2.Text = 'GLCM';
            app.pushbutton3 = uibutton(app.uipanel1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Tag = 'pushbutton3';
            app.pushbutton3.Text = {'Reset'; ''};
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = {'Pixel Distance:'; ''};
            app.edit1 = uieditfield(app.uipanel1, 'text');
            app.edit1.Tag = 'edit1';
            app.edit1.Value = '1';
            app.uitable1 = uitable(app.uipanel1);
            app.uitable1.ColumnName = {'0'; '45'; '90'; '135'; 'Average'};
            app.uitable1.RowName = {'Contrast'; 'Correlation'; 'Energy'; 'Homogeneity'};
            app.uitable1.ColumnEditable = [false false false false false];
            app.uitable1.Tag = 'uitable1';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = texture_analysis_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)texture_analysis_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end